classdef example < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WorkloadMbSliderLabel           matlab.ui.control.Label
        WorkloadMbSlider                matlab.ui.control.Slider
        BandwidthButtonGroup_2          matlab.ui.container.ButtonGroup
        fiveMHzButton                   matlab.ui.control.RadioButton
        tenMHzButton                    matlab.ui.control.RadioButton
        fifteenMHzButton                matlab.ui.control.RadioButton
        thirtyMHzButton                 matlab.ui.control.RadioButton
        fourtyMHzButton                 matlab.ui.control.RadioButton
        fiftyMHzButton                  matlab.ui.control.RadioButton
        sixtyMHzButton                  matlab.ui.control.RadioButton
        eghtyMHzButton                  matlab.ui.control.RadioButton
        ninetyMHzButton                 matlab.ui.control.RadioButton
        hundredMHzButton                matlab.ui.control.RadioButton
        twentyMHzButton                 matlab.ui.control.RadioButton
        twentyfiveMHzButton             matlab.ui.control.RadioButton
        seventyMHzButton                matlab.ui.control.RadioButton
        Adjustparametersforthewirelesschannel5GNRLabel  matlab.ui.control.Label
        SpatialstreamsButtonGroup_2     matlab.ui.container.ButtonGroup
        SISOButton_2                    matlab.ui.control.RadioButton
        MIMO2x2Button_2                 matlab.ui.control.RadioButton
        MIMO3x3Button_2                 matlab.ui.control.RadioButton
        MIMO4x4Button_2                 matlab.ui.control.RadioButton
        MIMO6x6Button_2                 matlab.ui.control.RadioButton
        MIMO8x8Button_2                 matlab.ui.control.RadioButton
        ModulationButtonGroup_2         matlab.ui.container.ButtonGroup
        QPSKButton_2                    matlab.ui.control.RadioButton
        QAM16Button_2                   matlab.ui.control.RadioButton
        QAM64Button_2                   matlab.ui.control.RadioButton
        QAM256Button_2                  matlab.ui.control.RadioButton
        GHzLabel                        matlab.ui.control.Label
        GHzLabel_3                      matlab.ui.control.Label
        GHzLabel_2                      matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        OverallresponsetimesLabel       matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        OverallpowerconsumptionWLabel   matlab.ui.control.Label
        BitrateMbpsLabel                matlab.ui.control.Label
        UITable2_3                      matlab.ui.control.Table
        UITable4                        matlab.ui.control.Table
        InputareaLabel                  matlab.ui.control.Label
        VbatterycapacityEditFieldLabel  matlab.ui.control.Label
        VbatterycapacityEditField       matlab.ui.control.NumericEditField
        AdjustthedevicesettingsLabel    matlab.ui.control.Label
        mAhLabel                        matlab.ui.control.Label
        OutputareaLabel                 matlab.ui.control.Label
        ComputingtimesLabel             matlab.ui.control.Label
        CommunicationtimesLabel         matlab.ui.control.Label
        UITable4_3                      matlab.ui.control.Table
        UITable2_4                      matlab.ui.control.Table
        UITable4_4                      matlab.ui.control.Table
        PowerconsumptiononcomputingLabel  matlab.ui.control.Label
        PowerconsumptiononcommunicationLabel  matlab.ui.control.Label
        UITable4_5                      matlab.ui.control.Table
        ResponsetimeLabel               matlab.ui.control.Label
        PowerConsumptionLabel           matlab.ui.control.Label
        AdjustparametersforthewiredchannelLabel  matlab.ui.control.Label
        SignaltoNoiseRatioButtonGroup   matlab.ui.container.ButtonGroup
        dBm10                           matlab.ui.control.RadioButton
        dBm15                           matlab.ui.control.RadioButton
        dBm20                           matlab.ui.control.RadioButton
        dBm30                           matlab.ui.control.RadioButton
        dBm40                           matlab.ui.control.RadioButton
        RunninghoursLabel               matlab.ui.control.Label
        UITable2_5                      matlab.ui.control.Table
        IoTdeviceCPUfrequencyEditFieldLabel  matlab.ui.control.Label
        IoTdeviceCPUfrequencyEditField  matlab.ui.control.NumericEditField
        MECserverprocessorfrequencyEditField_2Label  matlab.ui.control.Label
        MECserverprocessorfrequencyEditField  matlab.ui.control.NumericEditField
        MCCserverprocessorfrequencyEditField_2Label  matlab.ui.control.Label
        MCCserverprocessorfrequencyEditField  matlab.ui.control.NumericEditField
        UIAxesTIot                      matlab.ui.control.UIAxes
        UIAxesEIot                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Workload % Description
        NumberRB %Number of resource blocks for 5G
        ModulationCoefficient2 % number of bits in signal unit based on the modulation in 5G
        NumberSS_2 %Number of spatial streams for 5G
        numberiotCPU % IoT server processor frequency in Hz
        numberedgeCPU %MEC server ptocessor frequency in Hz
        numbercloudCPU %MCC server processor frequency in Hz
        snr % snr rate for wired channel
        amper %the battery capacity
    end

    
    methods (Access = private)
        
        function plotUpdate(app)
            w = app.Workload; % Mbit
            w1=w*10^6; % bit
            b = 1.1*10^3; % cycles/bit
            
            % IoT power consumption
            p_idle = 0.003; %Wh - IoT idle power, i.o. when the computing located outside the device
            p_tr = 1.2; %Wh - transmission power of IoT device
            p_ex = 0.9; %Wh - CPU computational power of IoT device
            
            %IoT model
            k = app.numberiotCPU;
            f_iot = k.*10^9; % Hz
            T_comp_iot = b*w1./f_iot; %in [s]
            T_iot = T_comp_iot;
            T_iot1= T_iot/3600; %in hours
            E_comp_iot = T_iot1*p_ex; % [W] P_iot - power consumption when computing in IoT device
            E_iot = E_comp_iot;
            
            % Calculating 5G NR bit rate
            N_rb = app.NumberRB;
            N_ss2 = app.NumberSS_2;
            Q2 = app.ModulationCoefficient2;
            %mu = 1; % numerology for subcarrier space 30kHz (in formula
            %2^mu)
            f = 0.75; %scaling factor
            r = 0.92578125; %max code rate
            %OH = 0.2; % max overhead (1-OH = 0.8)
            C = 10^(-6)*N_ss2*Q2*f*r*N_rb*12*14*2*0.8*1000; % 5G NR bit rate in [Mbps]
            
            %MEC model
            k1 = app.numberedgeCPU;
            f_mec = k1.*10^9; % Hz
            T_comp_mec = b*w1./f_mec; % in [s]
          
            T_comm_mec = w./C; %communication time mec
            T_mec = T_comp_mec+T_comm_mec;
            E_comp_mec = p_idle*T_comp_mec/3600;
            E_comm_mec = p_tr*T_comm_mec/3600;
            E_mec = E_comp_mec+ E_comm_mec;
            
            %MCC model
            k2 = app.numbercloudCPU;
            f_mcc = k2.*10^9; %  Hz
            T_comp_mcc = b*w1./f_mcc;% in [s]
            
            % Calculating the wired channel delay
            sn=app.snr;
            W = 20*10^3*log2(1+sn);
            T_mcc2 = w./W; %communication time in wired channel
            T_mcc1 = w./C; %communication time 5G
            T_comm_mcc = T_mcc1 + T_mcc2;
            T_mcc = T_comp_mcc+T_comm_mcc;
            E_comp_mcc = p_idle*T_comp_mcc/3600;
            E_comm_mcc1 = p_tr*T_mcc1/3600;
            E_comm_mcc2 = p_idle*T_mcc2/3600;
            E_comm_mcc = E_comm_mcc1 + E_comm_mcc2;
            E_mcc = E_comp_mcc+ E_comm_mcc;
            
            %plotting
            x=categorical({'IoT-only', 'MEC-only', 'MCC-only'});
            x=reordercats(x,{'IoT-only', 'MEC-only', 'MCC-only'});
            T=[0, 0, 0, T_comp_iot;T_comp_mec, 0, T_comm_mec, 0; T_comp_mcc, T_mcc2,  T_mcc1, 0];
            E=[0, 0, 0, E_comp_iot;E_comp_mec, 0, E_comm_mec, 0; E_comp_mcc, E_comm_mcc2, E_comm_mcc1, 0];
            bar(app.UIAxesTIot,x,T, 'stacked', 'FaceColor',"flat");
            set(app.UIAxesTIot, 'YScale', 'log')
            ylim(app.UIAxesTIot,[0 10]);
            legend(app.UIAxesTIot,'Offladed Computing','Wired Communication', 'Wireless Communication','Local computing')
            bar(app.UIAxesEIot,x,E, 'stacked');
            %ylim(app.UIAxesEIot,[0 0.0015]);
            set(app.UIAxesEIot, 'YScale', 'log')
            legend(app.UIAxesEIot,'Idle state for offloaded computing','Idle for wired communication', 'Wireless Communication','Local computing')
            
            %running hours
            A = app.amper; %mAh
            V=3.3; %V - the operating voltage
            Wtts = A * 10^(-3)*V; %mWh
            Wtts1 = Wtts /10^3; %Wh
            H_iot = Wtts1/E_iot;
            H_mec = Wtts1/E_mec;
            H_mcc = Wtts1/E_mcc;
            
            %display values
            app.UITable.Data = {app.Workload};
            app.UITable2_3.Data = {num2str(C), num2str(W)};
            app.UITable4.Data = {sprintf('%0.9f',T_iot),sprintf('%0.9f',T_comp_mec),sprintf('%0.9f',T_comp_mcc)}; %computing time
            app.UITable4_3.Data = {sprintf('%0.9f',T_comm_mec),sprintf('%0.9f',T_comm_mcc)}; % communication time
            app.UITable2.Data = {sprintf('%0.9f',T_iot),sprintf('%0.9f',T_mec),sprintf('%0.9f',T_mcc)}; %overall time
            app.UITable4_4.Data = {sprintf('%0.9f',E_iot),sprintf('%0.15f',E_comp_mec),sprintf('%0.15f',E_comp_mcc)}; %power consumption on computing
            app.UITable4_5.Data = {sprintf('%0.15f',E_comm_mec),sprintf('%0.15f',E_comm_mcc)}; %power consumption on communication
            app.UITable2_4.Data = {sprintf('%0.15f',E_iot),sprintf('%0.15f',E_mec),sprintf('%0.15f',E_mcc)}; % overall power
            app.UITable2_5.Data = {num2str(H_iot),num2str(H_mec),num2str(H_mcc)}; % the running hours
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NumberRB = 106;
            app.ModulationCoefficient2 = 6;
            app.NumberSS_2 = 1;
            app.numberiotCPU = 2;
            app.numberedgeCPU = 75;
            app.numbercloudCPU = 288;
            app.snr = 2.7;
            app.amper = 8390;
        end

        % Value changed function: WorkloadMbSlider
        function WorkloadMbSliderValueChanged(app, event)
            app.Workload = app.WorkloadMbSlider.Value;
            plotUpdate(app);
        end

        % Selection changed function: BandwidthButtonGroup_2
        function BandwidthButtonGroup_2SelectionChanged(app, event)
            selectedButton4 = app.BandwidthButtonGroup_2.SelectedObject;
            
            if selectedButton4 == app.fiveMHzButton
                app.NumberRB = 11;
                plotUpdate(app);
            elseif selectedButton4 == app.tenMHzButton
                app.NumberRB = 24;
                plotUpdate(app);
            elseif selectedButton4 == app.fifteenMHzButton
                app.NumberRB = 38;
                plotUpdate(app);
            elseif selectedButton4 == app.twentyMHzButton
                app.NumberRB = 51;
                plotUpdate(app);
            elseif selectedButton4 == app.twentyfiveMHzButton
                app.NumberRB = 65;
                plotUpdate(app);    
            elseif selectedButton4 == app.thirtyMHzButton
                app.NumberRB = 78;
                plotUpdate(app);
            elseif selectedButton4 == app.fourtyMHzButton
                app.NumberRB = 106;
                plotUpdate(app);
            elseif selectedButton4 == app.fiftyMHzButton
                app.NumberRB = 133;
                plotUpdate(app);
            elseif selectedButton4 == app.sixtyMHzButton
                app.NumberRB = 162;
                plotUpdate(app);
            elseif selectedButton4 == app.seventyMHzButton
                app.NumberRB = 189;
                plotUpdate(app);    
            elseif selectedButton4 == app.eghtyMHzButton
                app.NumberRB = 217;
                plotUpdate(app);
            elseif selectedButton4 == app.ninetyMHzButton
                app.NumberRB = 245;
                plotUpdate(app);
            elseif selectedButton4 == app.hundredMHzButton
                app.NumberRB = 273;
                plotUpdate(app);
            
            end
        end

        % Selection changed function: ModulationButtonGroup_2
        function ModulationButtonGroup_2SelectionChanged(app, event)
            selectedButton5 = app.ModulationButtonGroup_2.SelectedObject;
            
            if selectedButton5 == app.QPSKButton_2
                app.ModulationCoefficient2 = 2;
                plotUpdate(app);
            elseif selectedButton5 == app.QAM16Button_2
                app.ModulationCoefficient2 = 4;
                plotUpdate(app);
            elseif selectedButton5 == app.QAM64Button_2
                app.ModulationCoefficient2 = 6;
                plotUpdate(app);
            elseif selectedButton5 == app.QAM256Button_2
                app.ModulationCoefficient2 = 8;
                plotUpdate(app);
            end
        end

        % Selection changed function: SpatialstreamsButtonGroup_2
        function SpatialstreamsButtonGroup_2SelectionChanged(app, event)
            selectedButton6 = app.SpatialstreamsButtonGroup_2.SelectedObject;
            
            if selectedButton6 == app.SISOButton_2
                app.NumberSS_2 = 1;
                plotUpdate(app);
            elseif selectedButton6 == app.MIMO2x2Button_2
                app.NumberSS_2 = 2;
                plotUpdate(app);
            elseif selectedButton6 == app.MIMO3x3Button_2
                app.NumberSS_2 = 3;
                plotUpdate(app);
            elseif selectedButton6 == app.MIMO4x4Button_2
                app.NumberSS_2 = 4;
                plotUpdate(app);
            elseif selectedButton6 == app.MIMO6x6Button_2
                app.NumberSS_2 = 6;
                plotUpdate(app);
            elseif selectedButton6 == app.MIMO8x8Button_2
                app.NumberSS_2 = 8;
                plotUpdate(app);
            end
        end

        % Selection changed function: SignaltoNoiseRatioButtonGroup
        function SignaltoNoiseRatioButtonGroupSelectionChanged(app, event)
            selectedButton7 = app.SignaltoNoiseRatioButtonGroup.SelectedObject;
            
            if selectedButton7 == app.dBm10
                app.snr = 2.7;
                plotUpdate(app);
            elseif selectedButton7 == app.dBm15
                app.snr = 4.5;
                plotUpdate(app);
            elseif selectedButton7 == app.dBm20
                app.snr = 7.4;
                plotUpdate(app);
            elseif selectedButton7 == app.dBm30
                app.snr = 20;
                plotUpdate(app);
            elseif selectedButton7 == app.dBm40
                app.snr = 54.6;
                plotUpdate(app);
            end
        end

        % Value changed function: VbatterycapacityEditField
        function VbatterycapacityEditFieldValueChanged(app, event)
            app.amper = app.VbatterycapacityEditField.Value;
            plotUpdate(app);
        end

        % Value changed function: IoTdeviceCPUfrequencyEditField
        function IoTdeviceCPUfrequencyEditFieldValueChanged(app, event)
            app.numberiotCPU = app.IoTdeviceCPUfrequencyEditField.Value;
            plotUpdate(app);
        end

        % Value changed function: 
        % MECserverprocessorfrequencyEditField
        function MECserverprocessorfrequencyEditFieldValueChanged(app, event)
            app.numberedgeCPU = app.MECserverprocessorfrequencyEditField.Value;
            plotUpdate(app);
        end

        % Value changed function: 
        % MCCserverprocessorfrequencyEditField
        function MCCserverprocessorfrequencyEditFieldValueChanged(app, event)
            app.numbercloudCPU = app.MCCserverprocessorfrequencyEditField.Value;
            plotUpdate(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WorkloadMbSliderLabel = uilabel(app.UIFigure);
            app.WorkloadMbSliderLabel.Text = 'Workload [Mb]:';
            app.WorkloadMbSlider = uislider(app.UIFigure);
            app.WorkloadMbSlider.Limits = [0.001 1000];
            app.WorkloadMbSlider.ValueChangedFcn = createCallbackFcn(app, @WorkloadMbSliderValueChanged, true);
            app.WorkloadMbSlider.Value = 50;
            app.BandwidthButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.BandwidthButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @BandwidthButtonGroup_2SelectionChanged, true);
            app.BandwidthButtonGroup_2.Title = 'Bandwidth:';
            app.fiveMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.fiveMHzButton.Text = '5 MHz';
            app.tenMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.tenMHzButton.Text = '10 MHz';
            app.fifteenMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.fifteenMHzButton.Text = '15 MHz';
            app.thirtyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.thirtyMHzButton.Text = '30 MHz';
            app.fourtyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.fourtyMHzButton.Text = '40 MHz';
            app.fourtyMHzButton.Value = true;
            app.fiftyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.fiftyMHzButton.Text = '50 MHz';
            app.sixtyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.sixtyMHzButton.Text = '60 MHz';
            app.eghtyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.eghtyMHzButton.Text = '80 MHz';
            app.ninetyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.ninetyMHzButton.Text = '90 MHz';
            app.hundredMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.hundredMHzButton.Text = '100 MHz';
            app.twentyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.twentyMHzButton.Text = '20 MHz';
            app.twentyfiveMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.twentyfiveMHzButton.Text = '25 MHz';
            app.seventyMHzButton = uiradiobutton(app.BandwidthButtonGroup_2);
            app.seventyMHzButton.Text = '70 MHz';
            app.Adjustparametersforthewirelesschannel5GNRLabel = uilabel(app.UIFigure);
            app.Adjustparametersforthewirelesschannel5GNRLabel.Text = '2. Adjust parameters for the wireless channel (5G NR):';
            app.SpatialstreamsButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.SpatialstreamsButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SpatialstreamsButtonGroup_2SelectionChanged, true);
            app.SpatialstreamsButtonGroup_2.Title = 'Spatial streams:';
            app.SISOButton_2 = uiradiobutton(app.SpatialstreamsButtonGroup_2);
            app.SISOButton_2.Text = 'SISO';
            app.SISOButton_2.Value = true;
            app.MIMO2x2Button_2 = uiradiobutton(app.SpatialstreamsButtonGroup_2);
            app.MIMO2x2Button_2.Text = 'MIMO 2x2';
            app.MIMO3x3Button_2 = uiradiobutton(app.SpatialstreamsButtonGroup_2);
            app.MIMO3x3Button_2.Text = 'MIMO 3x3';
            app.MIMO4x4Button_2 = uiradiobutton(app.SpatialstreamsButtonGroup_2);
            app.MIMO4x4Button_2.Text = 'MIMO 4x4';
            app.MIMO6x6Button_2 = uiradiobutton(app.SpatialstreamsButtonGroup_2);
            app.MIMO6x6Button_2.Text = 'MIMO 6x6';
            app.MIMO8x8Button_2 = uiradiobutton(app.SpatialstreamsButtonGroup_2);
            app.MIMO8x8Button_2.Text = 'MIMO 8x8';
            app.ModulationButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ModulationButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ModulationButtonGroup_2SelectionChanged, true);
            app.ModulationButtonGroup_2.Title = 'Modulation:';
            app.QPSKButton_2 = uiradiobutton(app.ModulationButtonGroup_2);
            app.QPSKButton_2.Text = 'QPSK';
            app.QAM16Button_2 = uiradiobutton(app.ModulationButtonGroup_2);
            app.QAM16Button_2.Text = 'QAM-16';
            app.QAM64Button_2 = uiradiobutton(app.ModulationButtonGroup_2);
            app.QAM64Button_2.Text = 'QAM-64';
            app.QAM64Button_2.Value = true;
            app.QAM256Button_2 = uiradiobutton(app.ModulationButtonGroup_2);
            app.QAM256Button_2.Text = 'QAM-256';
            app.GHzLabel = uilabel(app.UIFigure);
            app.GHzLabel.Text = 'GHz';
            app.GHzLabel_3 = uilabel(app.UIFigure);
            app.GHzLabel_3.Text = 'GHz';
            app.GHzLabel_2 = uilabel(app.UIFigure);
            app.GHzLabel_2.Text = 'GHz';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Workload [Mb]:'};
            app.UITable.RowName = {};
            app.OverallresponsetimesLabel = uilabel(app.UIFigure);
            app.OverallresponsetimesLabel.Text = 'Overall response time [s]:';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'IoT'; 'MEC'; 'MCC'};
            app.UITable2.RowName = {};
            app.OverallpowerconsumptionWLabel = uilabel(app.UIFigure);
            app.OverallpowerconsumptionWLabel.Text = 'Overall power consumption [W]:';
            app.BitrateMbpsLabel = uilabel(app.UIFigure);
            app.BitrateMbpsLabel.Text = 'Bit rate [Mbps]:';
            app.UITable2_3 = uitable(app.UIFigure);
            app.UITable2_3.ColumnName = {'5G NR'; 'Optic fiber'};
            app.UITable2_3.RowName = {};
            app.UITable4 = uitable(app.UIFigure);
            app.UITable4.ColumnName = {'IoT'; 'MEC'; 'MCC'};
            app.UITable4.RowName = {};
            app.InputareaLabel = uilabel(app.UIFigure);
            app.InputareaLabel.Text = 'Input area';
            app.VbatterycapacityEditFieldLabel = uilabel(app.UIFigure);
            app.VbatterycapacityEditFieldLabel.Text = '4V battery capacity';
            app.VbatterycapacityEditField = uieditfield(app.UIFigure, 'numeric');
            app.VbatterycapacityEditField.ValueChangedFcn = createCallbackFcn(app, @VbatterycapacityEditFieldValueChanged, true);
            app.VbatterycapacityEditField.Value = 8390;
            app.AdjustthedevicesettingsLabel = uilabel(app.UIFigure);
            app.AdjustthedevicesettingsLabel.Text = '1. Adjust the device settings';
            app.mAhLabel = uilabel(app.UIFigure);
            app.mAhLabel.Text = 'mAh';
            app.OutputareaLabel = uilabel(app.UIFigure);
            app.OutputareaLabel.Text = 'Output area';
            app.ComputingtimesLabel = uilabel(app.UIFigure);
            app.ComputingtimesLabel.Text = 'Computing time [s]:';
            app.CommunicationtimesLabel = uilabel(app.UIFigure);
            app.CommunicationtimesLabel.Text = 'Communication time [s]:';
            app.UITable4_3 = uitable(app.UIFigure);
            app.UITable4_3.ColumnName = {'MEC'; 'MCC'};
            app.UITable4_3.RowName = {};
            app.UITable2_4 = uitable(app.UIFigure);
            app.UITable2_4.ColumnName = {'IoT'; 'MEC'; 'MCC'};
            app.UITable2_4.RowName = {};
            app.UITable4_4 = uitable(app.UIFigure);
            app.UITable4_4.ColumnName = {'IoT'; 'MEC'; 'MCC'};
            app.UITable4_4.RowName = {};
            app.PowerconsumptiononcomputingLabel = uilabel(app.UIFigure);
            app.PowerconsumptiononcomputingLabel.Text = 'Power consumption on computing:';
            app.PowerconsumptiononcommunicationLabel = uilabel(app.UIFigure);
            app.PowerconsumptiononcommunicationLabel.Text = 'Power consumption on communication:';
            app.UITable4_5 = uitable(app.UIFigure);
            app.UITable4_5.ColumnName = {'MEC'; 'MCC'};
            app.UITable4_5.RowName = {};
            app.ResponsetimeLabel = uilabel(app.UIFigure);
            app.ResponsetimeLabel.Text = 'Response time';
            app.PowerConsumptionLabel = uilabel(app.UIFigure);
            app.PowerConsumptionLabel.Text = 'Power Consumption';
            app.AdjustparametersforthewiredchannelLabel = uilabel(app.UIFigure);
            app.AdjustparametersforthewiredchannelLabel.Text = '3. Adjust parameters for the wired channel:';
            app.SignaltoNoiseRatioButtonGroup = uibuttongroup(app.UIFigure);
            app.SignaltoNoiseRatioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SignaltoNoiseRatioButtonGroupSelectionChanged, true);
            app.SignaltoNoiseRatioButtonGroup.Title = 'Signal-to-Noise Ratio';
            app.dBm10 = uiradiobutton(app.SignaltoNoiseRatioButtonGroup);
            app.dBm10.Text = '10 dBm';
            app.dBm10.Value = true;
            app.dBm15 = uiradiobutton(app.SignaltoNoiseRatioButtonGroup);
            app.dBm15.Text = '15 dBm';
            app.dBm20 = uiradiobutton(app.SignaltoNoiseRatioButtonGroup);
            app.dBm20.Text = '20 dBm';
            app.dBm30 = uiradiobutton(app.SignaltoNoiseRatioButtonGroup);
            app.dBm30.Text = '30 dBm';
            app.dBm40 = uiradiobutton(app.SignaltoNoiseRatioButtonGroup);
            app.dBm40.Text = '40 dBm';
            app.RunninghoursLabel = uilabel(app.UIFigure);
            app.RunninghoursLabel.Text = 'Running hours:';
            app.UITable2_5 = uitable(app.UIFigure);
            app.UITable2_5.ColumnName = {'IoT'; 'MEC'; 'MCC'};
            app.UITable2_5.RowName = {};
            app.IoTdeviceCPUfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.IoTdeviceCPUfrequencyEditFieldLabel.Text = 'IoT device CPU frequency';
            app.IoTdeviceCPUfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.IoTdeviceCPUfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @IoTdeviceCPUfrequencyEditFieldValueChanged, true);
            app.IoTdeviceCPUfrequencyEditField.Value = 2;
            app.MECserverprocessorfrequencyEditField_2Label = uilabel(app.UIFigure);
            app.MECserverprocessorfrequencyEditField_2Label.Text = 'MEC server processor frequency:';
            app.MECserverprocessorfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MECserverprocessorfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @MECserverprocessorfrequencyEditFieldValueChanged, true);
            app.MECserverprocessorfrequencyEditField.Value = 75;
            app.MCCserverprocessorfrequencyEditField_2Label = uilabel(app.UIFigure);
            app.MCCserverprocessorfrequencyEditField_2Label.Text = 'MCC server processor frequency:';
            app.MCCserverprocessorfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MCCserverprocessorfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @MCCserverprocessorfrequencyEditFieldValueChanged, true);
            app.MCCserverprocessorfrequencyEditField.Value = 288;
            app.UIAxesTIot = uiaxes(app.UIFigure);
            ylabel(app.UIAxesTIot, 'Time [s]')
            zlabel(app.UIAxesTIot, 'Z')
            app.UIAxesEIot = uiaxes(app.UIFigure);
            ylabel(app.UIAxesEIot, 'Power Consumption [W]')
            zlabel(app.UIAxesEIot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = example

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end